classdef DSVerifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSVerifierAppUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TransferFunctionTab             matlab.ui.container.Tab
        DigitalSystemTransferFunctionRepresentationPanel  matlab.ui.container.Panel
        NumeratorEditFieldLabel         matlab.ui.control.Label
        NumeratorEditField              matlab.ui.control.EditField
        DenominatorEditFieldLabel       matlab.ui.control.Label
        DenominatorEditField            matlab.ui.control.EditField
        SampleTimeEditFieldLabel        matlab.ui.control.Label
        SampleTimeEditField             matlab.ui.control.EditField
        ImplementationPanel             matlab.ui.container.Panel
        IntegerBitsSpinnerLabel         matlab.ui.control.Label
        IntegerBitsSpinner              matlab.ui.control.Spinner
        DeltaEditFieldLabel             matlab.ui.control.Label
        DeltaEditField                  matlab.ui.control.EditField
        maxrangeSpinnerLabel            matlab.ui.control.Label
        maxrangeSpinner                 matlab.ui.control.Spinner
        minrangeSpinnerLabel            matlab.ui.control.Label
        minrangeSpinner                 matlab.ui.control.Spinner
        TimeoutEditFieldLabel           matlab.ui.control.Label
        TimeoutEditField                matlab.ui.control.EditField
        KboundSlider_3Label             matlab.ui.control.Label
        KboundSlider_3                  matlab.ui.control.Slider
        RealizationFormDropDownLabel    matlab.ui.control.Label
        RealizationFormDropDown         matlab.ui.control.DropDown
        ErrorLimitEditFieldLabel        matlab.ui.control.Label
        ErrorLimitEditField             matlab.ui.control.EditField
        FractionalBitsSpinnerLabel      matlab.ui.control.Label
        FractionalBitsSpinner           matlab.ui.control.Spinner
        PropertiesPanel                 matlab.ui.container.Panel
        StabilityCheckBox               matlab.ui.control.CheckBox
        MinimumPhaseCheckBox            matlab.ui.control.CheckBox
        OverflowCheckBox                matlab.ui.control.CheckBox
        LimitCycleCheckBox              matlab.ui.control.CheckBox
        QuantizationErrorCheckBox_3     matlab.ui.control.CheckBox
        ConfigurationsPanel             matlab.ui.container.Panel
        BMCDropDownLabel                matlab.ui.control.Label
        BMCDropDown                     matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        OverflowModeDropDownLabel       matlab.ui.control.Label
        OverflowModeDropDown            matlab.ui.control.DropDown
        RoundingModeDropDownLabel       matlab.ui.control.Label
        RoundingModeDropDown            matlab.ui.control.DropDown
        ErrorModeDropDownLabel          matlab.ui.control.Label
        ErrorModeDropDown               matlab.ui.control.DropDown
        VerifyButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ClosedLoopTab                   matlab.ui.container.Tab
        ClosedLoopRepresentationPanel   matlab.ui.container.Panel
        PlantPanel                      matlab.ui.container.Panel
        NumeratorEditField_3Label       matlab.ui.control.Label
        NumeratorEditField_3            matlab.ui.control.EditField
        DenominatorEditField_3Label     matlab.ui.control.Label
        DenominatorEditField_3          matlab.ui.control.EditField
        ImplementationPanel_2           matlab.ui.container.Panel
        IntegerBitsSpinner_2Label       matlab.ui.control.Label
        IntegerBitsSpinner_2            matlab.ui.control.Spinner
        FractionalBitsSpinner_2Label    matlab.ui.control.Label
        FractionalBitsSpinner_2         matlab.ui.control.Spinner
        ErrorLimitEditField_2Label      matlab.ui.control.Label
        ErrorLimitEditField_2           matlab.ui.control.EditField
        maxrangeSpinner_2Label          matlab.ui.control.Label
        maxrangeSpinner_2               matlab.ui.control.Spinner
        minrangeSpinner_2Label          matlab.ui.control.Label
        minrangeSpinner_2               matlab.ui.control.Spinner
        TimeoutEditField_2Label         matlab.ui.control.Label
        TimeoutEditField_2              matlab.ui.control.EditField
        KboundSlider_2Label             matlab.ui.control.Label
        KboundSlider_2                  matlab.ui.control.Slider
        RealizationFormDropDown_2Label  matlab.ui.control.Label
        RealizationFormDropDown_2       matlab.ui.control.DropDown
        PropertiesPanel_2               matlab.ui.container.Panel
        StabilityCheckBox_3             matlab.ui.control.CheckBox
        QuantizationErrorCheckBox       matlab.ui.control.CheckBox
        LimitCycleCheckBox_2            matlab.ui.control.CheckBox
        ConfigurationsPanel_2           matlab.ui.container.Panel
        BMCDropDown_2Label              matlab.ui.control.Label
        BMCDropDown_2                   matlab.ui.control.DropDown
        SolverDropDown_2Label           matlab.ui.control.Label
        SolverDropDown_2                matlab.ui.control.DropDown
        OverflowModeDropDown_2Label     matlab.ui.control.Label
        OverflowModeDropDown_2          matlab.ui.control.DropDown
        RoundingModeDropDown_2Label     matlab.ui.control.Label
        RoundingModeDropDown_2          matlab.ui.control.DropDown
        ErrorModeDropDown_2Label        matlab.ui.control.Label
        ErrorModeDropDown_2             matlab.ui.control.DropDown
        ConnectionModeDropDownLabel     matlab.ui.control.Label
        ConnectionModeDropDown          matlab.ui.control.DropDown
        ControllerPanel                 matlab.ui.container.Panel
        NumeratorEditField_4Label       matlab.ui.control.Label
        NumeratorEditField_4            matlab.ui.control.EditField
        DenominatorEditField_4Label     matlab.ui.control.Label
        DenominatorEditField_4          matlab.ui.control.EditField
        SampleTimeEditField_2Label      matlab.ui.control.Label
        SampleTimeEditField_2           matlab.ui.control.EditField
        VerifyButton_2                  matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        StateSpaceTab                   matlab.ui.container.Tab
        StateSpaceMatrixABCDPanel       matlab.ui.container.Panel
        AEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.EditField
        BEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.EditField
        CEditFieldLabel                 matlab.ui.control.Label
        CEditField                      matlab.ui.control.EditField
        DEditFieldLabel                 matlab.ui.control.Label
        DEditField                      matlab.ui.control.EditField
        ImplementationPanel_3           matlab.ui.container.Panel
        IntegerBitsSpinner_3Label       matlab.ui.control.Label
        IntegerBitsSpinner_3            matlab.ui.control.Spinner
        FractionalBitsSpinner_3Label    matlab.ui.control.Label
        FractionalBitsSpinner_3         matlab.ui.control.Spinner
        InputMatrixEditFieldLabel       matlab.ui.control.Label
        InputMatrixEditField            matlab.ui.control.EditField
        maxrangeSpinner_3Label          matlab.ui.control.Label
        maxrangeSpinner_3               matlab.ui.control.Spinner
        minrangeSpinner_3Label          matlab.ui.control.Label
        minrangeSpinner_3               matlab.ui.control.Spinner
        KMatrixEditFieldLabel           matlab.ui.control.Label
        KMatrixEditField                matlab.ui.control.EditField
        KboundSliderLabel               matlab.ui.control.Label
        KboundSlider                    matlab.ui.control.Slider
        ErrorLimitEditField_3Label      matlab.ui.control.Label
        ErrorLimitEditField_3           matlab.ui.control.EditField
        ConfigurationsPanel_3           matlab.ui.container.Panel
        BMCDropDown_3Label              matlab.ui.control.Label
        BMCDropDown_3                   matlab.ui.control.DropDown
        SolverDropDown_3Label           matlab.ui.control.Label
        SolverDropDown_3                matlab.ui.control.DropDown
        VerifyButton_3                  matlab.ui.control.Button
        ResetButton_3                   matlab.ui.control.Button
        PropertiesPanel_3               matlab.ui.container.Panel
        StabilityCheckBox_2             matlab.ui.control.CheckBox
        QuantizationErrorCheckBox_2     matlab.ui.control.CheckBox
        ObservabilityCheckBox           matlab.ui.control.CheckBox
        ControllabilityCheckBox         matlab.ui.control.CheckBox
    end


    methods (Access = private)
    
        function results = verifyTransferFunction(app)
            
            numerator = str2num(app.NumeratorEditField.Value);
            denominator = str2num(app.DenominatorEditField.Value);
            sample = str2num(app.SampleTimeEditField.Value);
            intbits = app.IntegerBitsSpinner.Value;
            fracbits = app.FractionalBitsSpinner.Value;
            delta  = app.DeltaEditField.Value;
            maxrange = app.maxrangeSpinner.Value;
            minrange = app.minrangeSpinner.Value;
            timeout = str2num(app.TimeoutEditField.Value);
            kbound = app.KboundSlider_3.Value;
            realization = app.RealizationFormDropDown.Value;
            bmc = app.BMCDropDown.Value;
            solver = app.SolverDropDown.Value;
            overflow = app.OverflowModeDropDown.Value;
            rounding = app.RoundingModeDropDown.Value;
            error = app.ErrorModeDropDown.Value;
            limiterror = str2num(app.ErrorLimitEditField.Value);
            
            system = tf(numerator, denominator, sample);
            
            if (app.StabilityCheckBox.Value == 1)
                disp('Verifying Stability')
                verifyStability(system,intbits,fracbits,maxrange,minrange,realization, bmc);
            end
            if (app.MinimumPhaseCheckBox.Value == 1)
                disp('Verifying Minimum-Phase')
                verifyMinimumPhase(system,intbits,fracbits,maxrange,minrange,realization, bmc);
            end
            if (app.OverflowCheckBox.Value == 1)
                disp('Verifying Overflow')
                verifyOverflow(system,intbits,fracbits,maxrange,minrange,realization,kbound, bmc);
            end
            if (app.LimitCycleCheckBox.Value == 1)
                disp('Verifying Limit Cycle')
                verifyLimitCycle(system,intbits,fracbits,maxrange,minrange,realization,kbound, bmc);
            end
            if(app.QuantizationErrorCheckBox_3.Value == 1)
                disp('Verifying Quantization Error')
                verifyError(system,intbits,fracbits,maxrange,minrange,realization,kbound, limiterror, bmc);
            end                     
        end
        
        function results = verifyClosedLoop(app)
            
            numerator_controller = str2num(app.NumeratorEditField_4.Value);
            denominator_controller = str2num(app.DenominatorEditField_4.Value);
             
            numerator_plant = str2num(app.NumeratorEditField_3.Value);
            denominator_plant = str2num(app.DenominatorEditField_3.Value);

            sample = str2num(app.SampleTimeEditField_2.Value);
            
            intbits = app.IntegerBitsSpinner_2.Value;
            fracbits = app.FractionalBitsSpinner_2.Value;
            maxrange = app.maxrangeSpinner_2.Value;
            minrange = app.minrangeSpinner_2.Value;
            timeout = str2num(app.TimeoutEditField_2.Value);
            kbound = app.KboundSlider_2.Value;
            
            realization = app.RealizationFormDropDown_2.Value;
            bmc = app.BMCDropDown_2.Value;
            solver = app.SolverDropDown_2.Value;
            overflow = app.OverflowModeDropDown_2.Value;
            rounding = app.RoundingModeDropDown_2.Value;
            error = app.ErrorModeDropDown_2.Value;
            limiterror = str2num(app.ErrorLimitEditField_2.Value);
            connectionmode = app.ConnectionModeDropDown.Value;
            
            controller = tf(numerator_controller, denominator_controller, sample);
            plant = tf(numerator_plant, denominator_plant, sample);
            
            if (app.StabilityCheckBox_3.Value == 1)
                disp('Verifying Closed-Loop Stability')
                verifyClosedLoopStability(controller, plant,intbits,fracbits,maxrange,minrange,realization, connectionmode, bmc);
            end
            if (app.QuantizationErrorCheckBox.Value == 1)
                disp('Verifying Closed-Loop Quantization Error')
                verifyClosedLoopQuantizationError(controller, plant,intbits,fracbits,maxrange,minrange,realization, kbound, limiterror, connectionmode, bmc);
            end
            if (app.LimitCycleCheckBox_2.Value == 1)
                disp('Verifying Closed-Loop Limit Cycle')
                verifyClosedLoopLimitCycle(controller, plant, intbits,fracbits,maxrange,minrange,realization, kbound, connectionmode, bmc);
            end
        end

        function results = verifySpaceState(app)
            
            A = str2num(app.AEditField.Value);
            B = str2num(app.BEditField.Value);
            C = str2num(app.CEditField.Value);
            D = str2num(app.DEditField.Value);
            
            system = ss(A,B,C,D,1);
            
            intbits = app.IntegerBitsSpinner_3.Value;
            fracbits =  app.FractionalBitsSpinner_3.Value;
            inputs = str2num(app.InputMatrixEditField.Value);
            maxrange = app.maxrangeSpinner_3.Value;
            minrange = app.minrangeSpinner_3.Value;
            K = str2num(app.KMatrixEditField.Value);
            kbound = app.KboundSlider.Value;
            errorlimit = str2num(app.ErrorLimitEditField_3.Value); 
            
            bmc = app.BMCDropDown_3.Value;
            solver = app.BMCDropDown_3.Value;
                        
            if (app.StabilityCheckBox_2.Value == 1)
                disp('Verifying Closed-Loop Stability')
                verifyStateSpaceStability(system, inputs, intbits,fracbits,maxrange,minrange, K, bmc, solver);
            end
            if (app.QuantizationErrorCheckBox_2.Value == 1)
                disp('Verifying Closed-Loop Quantization Error')
                verifyStateSpaceQuantizationError(system, inputs, intbits,fracbits,maxrange,minrange, errorlimit, kbound, K, bmc, solver);
            end
            if (app.ObservabilityCheckBox.Value == 1)
                disp('Verifying Closed-Loop Limit Cycle')
                verifyStateSpaceObservability(system, inputs, intbits,fracbits,maxrange,minrange, K, bmc, solver);
            end
            if (app.ControllabilityCheckBox.Value == 1)
                disp('Verifying Closed-Loop Limit Cycle')
                verifyStateSpaceControllability(system, inputs, intbits,fracbits,maxrange,minrange, K, bmc, solver);
            end
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonTransferFunctionPushed(app, event)
            app.NumeratorEditField.Value = '';
            app.DenominatorEditField.Value = '';
            app.SampleTimeEditField.Value = '';
            app.IntegerBitsSpinner.Value = 0;
            app.FractionalBitsSpinner.Value = 0;
            app.DeltaEditField.Value = '';
            app.maxrangeSpinner.Value = 0;
            app.minrangeSpinner.Value = 0;
            app.TimeoutEditField.Value = '';
            app.KboundSlider_3.Value = 0;
            app.ErrorLimitEditField.Value = '';
        end

        % Button pushed function: VerifyButton
        function VerifyTransferFunctionButtonPushed(app, event)
            verifyTransferFunction(app)
        end

        % Button pushed function: ResetButton_2
        function ResetButtonClosedLoopPushed(app, event)
            app.NumeratorEditField_3.Value = '';
            app.DenominatorEditField_3.Value = '';
            app.IntegerBitsSpinner_2.Value = 0;
            app.FractionalBitsSpinner_2.Value = 0;
            app.ErrorLimitEditField_2.Value = '';
            app.maxrangeSpinner_2.Value = 0;
            app.minrangeSpinner_2.Value = 0;
            app.TimeoutEditField_2.Value = '';
            app.KboundSlider_2.Value = 0;
            app.StabilityCheckBox_3.Value = 0;
            app.QuantizationErrorCheckBox.Value = 0;
            app.LimitCycleCheckBox_2.Value = 0;
            app.NumeratorEditField_4.Value = '';
            app.DenominatorEditField_4.Value = '';
            app.SampleTimeEditField_2.Value = '';
        end

        % Button pushed function: VerifyButton_2
        function VerifyClosedLoopButtonPushed(app, event)
            verifyClosedLoop(app)
        end

        % Button pushed function: ResetButton_3
        function ResetButtonStateSpacePushed(app, event)
            app.AEditField.Value = '';
            app.BEditField.Value = '';
            app.CEditField.Value = '';
            app.DEditField.Value = '';
            app.IntegerBitsSpinner_3.Value = 0;
            app.FractionalBitsSpinner_3.Value = 0;
            app.InputMatrixEditField.Value = '';
            app.maxrangeSpinner_3.Value = 0;
            app.minrangeSpinner_3.Value = 0;
            app.KMatrixEditField.Value = '';
            app.KboundSlider.Value = 0;
            app.ErrorLimitEditField_3.Value = '';
            app.StabilityCheckBox_2.Value = 0;
            app.QuantizationErrorCheckBox_2.Value = 0;
            app.ObservabilityCheckBox.Value = 0;
            app.ControllabilityCheckBox.Value = 0;
        end

        % Button pushed function: VerifyButton_3
        function VerifyStateSpaceButtonPushed(app, event)
            verifySpaceState(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DSVerifierAppUIFigure = uifigure;
            app.DSVerifierAppUIFigure.Name = 'DSVerifier App';
            setAutoResize(app, app.DSVerifierAppUIFigure, true)
            app.TabGroup = uitabgroup(app.DSVerifierAppUIFigure);
            app.TransferFunctionTab = uitab(app.TabGroup);
            app.TransferFunctionTab.Title = 'Transfer Function';
            app.TransferFunctionTab.Units = 'pixels';
            app.DigitalSystemTransferFunctionRepresentationPanel = uipanel(app.TransferFunctionTab);
            app.DigitalSystemTransferFunctionRepresentationPanel.Title = 'Digital System - Transfer Function Representation';
            app.NumeratorEditFieldLabel = uilabel(app.DigitalSystemTransferFunctionRepresentationPanel);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.DigitalSystemTransferFunctionRepresentationPanel, 'text');
            app.DenominatorEditFieldLabel = uilabel(app.DigitalSystemTransferFunctionRepresentationPanel);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.DigitalSystemTransferFunctionRepresentationPanel, 'text');
            app.SampleTimeEditFieldLabel = uilabel(app.DigitalSystemTransferFunctionRepresentationPanel);
            app.SampleTimeEditFieldLabel.Text = 'Sample Time';
            app.SampleTimeEditField = uieditfield(app.DigitalSystemTransferFunctionRepresentationPanel, 'text');
            app.ImplementationPanel = uipanel(app.TransferFunctionTab);
            app.ImplementationPanel.Title = 'Implementation';
            app.IntegerBitsSpinnerLabel = uilabel(app.ImplementationPanel);
            app.IntegerBitsSpinnerLabel.Text = 'Integer Bits';
            app.IntegerBitsSpinner = uispinner(app.ImplementationPanel);
            app.DeltaEditFieldLabel = uilabel(app.ImplementationPanel);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.ImplementationPanel, 'text');
            app.maxrangeSpinnerLabel = uilabel(app.ImplementationPanel);
            app.maxrangeSpinnerLabel.Text = 'max range';
            app.maxrangeSpinner = uispinner(app.ImplementationPanel);
            app.maxrangeSpinner.Value = 1;
            app.minrangeSpinnerLabel = uilabel(app.ImplementationPanel);
            app.minrangeSpinnerLabel.Text = 'min range';
            app.minrangeSpinner = uispinner(app.ImplementationPanel);
            app.minrangeSpinner.Value = -1;
            app.TimeoutEditFieldLabel = uilabel(app.ImplementationPanel);
            app.TimeoutEditFieldLabel.Text = 'Timeout';
            app.TimeoutEditField = uieditfield(app.ImplementationPanel, 'text');
            app.KboundSlider_3Label = uilabel(app.ImplementationPanel);
            app.KboundSlider_3Label.Text = 'K bound';
            app.KboundSlider_3 = uislider(app.ImplementationPanel);
            app.RealizationFormDropDownLabel = uilabel(app.ImplementationPanel);
            app.RealizationFormDropDownLabel.Text = 'Realization Form';
            app.RealizationFormDropDown = uidropdown(app.ImplementationPanel);
            app.RealizationFormDropDown.Items = {'DFI', 'DFII', 'TDFII', 'DDFI', 'DDFII', 'TDDFII'};
            app.RealizationFormDropDown.Value = 'DFI';
            app.ErrorLimitEditFieldLabel = uilabel(app.ImplementationPanel);
            app.ErrorLimitEditFieldLabel.Text = 'Error Limit';
            app.ErrorLimitEditField = uieditfield(app.ImplementationPanel, 'text');
            app.FractionalBitsSpinnerLabel = uilabel(app.ImplementationPanel);
            app.FractionalBitsSpinnerLabel.Text = 'Fractional Bits';
            app.FractionalBitsSpinner = uispinner(app.ImplementationPanel);
            app.PropertiesPanel = uipanel(app.TransferFunctionTab);
            app.PropertiesPanel.Title = 'Properties';
            app.StabilityCheckBox = uicheckbox(app.PropertiesPanel);
            app.StabilityCheckBox.Text = 'Stability';
            app.MinimumPhaseCheckBox = uicheckbox(app.PropertiesPanel);
            app.MinimumPhaseCheckBox.Text = 'Minimum-Phase';
            app.OverflowCheckBox = uicheckbox(app.PropertiesPanel);
            app.OverflowCheckBox.Text = 'Overflow';
            app.LimitCycleCheckBox = uicheckbox(app.PropertiesPanel);
            app.LimitCycleCheckBox.Text = 'Limit Cycle';
            app.QuantizationErrorCheckBox_3 = uicheckbox(app.PropertiesPanel);
            app.QuantizationErrorCheckBox_3.Text = 'Quantization Error';
            app.ConfigurationsPanel = uipanel(app.TransferFunctionTab);
            app.ConfigurationsPanel.Title = 'Configurations';
            app.BMCDropDownLabel = uilabel(app.ConfigurationsPanel);
            app.BMCDropDownLabel.Text = 'BMC';
            app.BMCDropDown = uidropdown(app.ConfigurationsPanel);
            app.BMCDropDown.Items = {'CBMC', 'ESBMC'};
            app.BMCDropDown.Value = 'CBMC';
            app.SolverDropDownLabel = uilabel(app.ConfigurationsPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.ConfigurationsPanel);
            app.SolverDropDown.Items = {'Boolector', 'Z3', 'Minisat'};
            app.SolverDropDown.Value = 'Boolector';
            app.OverflowModeDropDownLabel = uilabel(app.ConfigurationsPanel);
            app.OverflowModeDropDownLabel.Text = 'Overflow Mode';
            app.OverflowModeDropDown = uidropdown(app.ConfigurationsPanel);
            app.OverflowModeDropDown.Items = {'Wrap-Around', 'Saturate', 'Detect Overflow'};
            app.OverflowModeDropDown.Value = 'Wrap-Around';
            app.RoundingModeDropDownLabel = uilabel(app.ConfigurationsPanel);
            app.RoundingModeDropDownLabel.Text = 'Rounding Mode';
            app.RoundingModeDropDown = uidropdown(app.ConfigurationsPanel);
            app.RoundingModeDropDown.Items = {'Round', 'Floor', 'Ceil'};
            app.RoundingModeDropDown.Value = 'Round';
            app.ErrorModeDropDownLabel = uilabel(app.ConfigurationsPanel);
            app.ErrorModeDropDownLabel.Text = 'Error  Mode';
            app.ErrorModeDropDown = uidropdown(app.ConfigurationsPanel);
            app.ErrorModeDropDown.Items = {'Absolute', 'Relative'};
            app.ErrorModeDropDown.Value = 'Absolute';
            app.VerifyButton = uibutton(app.TransferFunctionTab, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyTransferFunctionButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.ResetButton = uibutton(app.TransferFunctionTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonTransferFunctionPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ClosedLoopTab = uitab(app.TabGroup);
            app.ClosedLoopTab.Title = 'Closed-Loop';
            app.ClosedLoopTab.Units = 'pixels';
            app.ClosedLoopRepresentationPanel = uipanel(app.ClosedLoopTab);
            app.ClosedLoopRepresentationPanel.Title = 'Closed-Loop Representation';
            app.PlantPanel = uipanel(app.ClosedLoopRepresentationPanel);
            app.PlantPanel.Title = 'Plant';
            app.NumeratorEditField_3Label = uilabel(app.PlantPanel);
            app.NumeratorEditField_3Label.Text = 'Numerator';
            app.NumeratorEditField_3 = uieditfield(app.PlantPanel, 'text');
            app.DenominatorEditField_3Label = uilabel(app.PlantPanel);
            app.DenominatorEditField_3Label.Text = 'Denominator';
            app.DenominatorEditField_3 = uieditfield(app.PlantPanel, 'text');
            app.ImplementationPanel_2 = uipanel(app.ClosedLoopTab);
            app.ImplementationPanel_2.Title = 'Implementation';
            app.IntegerBitsSpinner_2Label = uilabel(app.ImplementationPanel_2);
            app.IntegerBitsSpinner_2Label.Text = 'Integer Bits';
            app.IntegerBitsSpinner_2 = uispinner(app.ImplementationPanel_2);
            app.FractionalBitsSpinner_2Label = uilabel(app.ImplementationPanel_2);
            app.FractionalBitsSpinner_2Label.Text = 'Fractional Bits';
            app.FractionalBitsSpinner_2 = uispinner(app.ImplementationPanel_2);
            app.ErrorLimitEditField_2Label = uilabel(app.ImplementationPanel_2);
            app.ErrorLimitEditField_2Label.Text = 'Error Limit';
            app.ErrorLimitEditField_2 = uieditfield(app.ImplementationPanel_2, 'text');
            app.maxrangeSpinner_2Label = uilabel(app.ImplementationPanel_2);
            app.maxrangeSpinner_2Label.Text = 'max range';
            app.maxrangeSpinner_2 = uispinner(app.ImplementationPanel_2);
            app.maxrangeSpinner_2.Value = 1;
            app.minrangeSpinner_2Label = uilabel(app.ImplementationPanel_2);
            app.minrangeSpinner_2Label.Text = 'min range';
            app.minrangeSpinner_2 = uispinner(app.ImplementationPanel_2);
            app.minrangeSpinner_2.Value = -1;
            app.TimeoutEditField_2Label = uilabel(app.ImplementationPanel_2);
            app.TimeoutEditField_2Label.Text = 'Timeout';
            app.TimeoutEditField_2 = uieditfield(app.ImplementationPanel_2, 'text');
            app.KboundSlider_2Label = uilabel(app.ImplementationPanel_2);
            app.KboundSlider_2Label.Text = 'K bound';
            app.KboundSlider_2 = uislider(app.ImplementationPanel_2);
            app.RealizationFormDropDown_2Label = uilabel(app.ImplementationPanel_2);
            app.RealizationFormDropDown_2Label.Text = 'Realization Form';
            app.RealizationFormDropDown_2 = uidropdown(app.ImplementationPanel_2);
            app.RealizationFormDropDown_2.Items = {'DFI', 'DFII', 'TDFII'};
            app.RealizationFormDropDown_2.Value = 'DFI';
            app.PropertiesPanel_2 = uipanel(app.ClosedLoopTab);
            app.PropertiesPanel_2.Title = 'Properties';
            app.StabilityCheckBox_3 = uicheckbox(app.PropertiesPanel_2);
            app.StabilityCheckBox_3.Text = 'Stability';
            app.QuantizationErrorCheckBox = uicheckbox(app.PropertiesPanel_2);
            app.QuantizationErrorCheckBox.Text = 'Quantization Error';
            app.LimitCycleCheckBox_2 = uicheckbox(app.PropertiesPanel_2);
            app.LimitCycleCheckBox_2.Text = 'Limit Cycle';
            app.ConfigurationsPanel_2 = uipanel(app.ClosedLoopTab);
            app.ConfigurationsPanel_2.Title = 'Configurations';
            app.BMCDropDown_2Label = uilabel(app.ConfigurationsPanel_2);
            app.BMCDropDown_2Label.Text = 'BMC';
            app.BMCDropDown_2 = uidropdown(app.ConfigurationsPanel_2);
            app.BMCDropDown_2.Items = {'CBMC', 'ESBMC'};
            app.BMCDropDown_2.Value = 'CBMC';
            app.SolverDropDown_2Label = uilabel(app.ConfigurationsPanel_2);
            app.SolverDropDown_2Label.Text = 'Solver';
            app.SolverDropDown_2 = uidropdown(app.ConfigurationsPanel_2);
            app.SolverDropDown_2.Items = {'Boolector', 'Z3', 'Minisat'};
            app.SolverDropDown_2.Value = 'Boolector';
            app.OverflowModeDropDown_2Label = uilabel(app.ConfigurationsPanel_2);
            app.OverflowModeDropDown_2Label.Text = 'Overflow Mode';
            app.OverflowModeDropDown_2 = uidropdown(app.ConfigurationsPanel_2);
            app.OverflowModeDropDown_2.Items = {'Wrap-Around', 'Saturate'};
            app.OverflowModeDropDown_2.Value = 'Wrap-Around';
            app.RoundingModeDropDown_2Label = uilabel(app.ConfigurationsPanel_2);
            app.RoundingModeDropDown_2Label.Text = 'Rounding Mode';
            app.RoundingModeDropDown_2 = uidropdown(app.ConfigurationsPanel_2);
            app.RoundingModeDropDown_2.Items = {'Round', 'Floor', 'Ceil'};
            app.RoundingModeDropDown_2.Value = 'Round';
            app.ErrorModeDropDown_2Label = uilabel(app.ConfigurationsPanel_2);
            app.ErrorModeDropDown_2Label.Text = 'Error  Mode';
            app.ErrorModeDropDown_2 = uidropdown(app.ConfigurationsPanel_2);
            app.ErrorModeDropDown_2.Items = {'Absolute', 'Relative'};
            app.ErrorModeDropDown_2.Value = 'Absolute';
            app.ConnectionModeDropDownLabel = uilabel(app.ConfigurationsPanel_2);
            app.ConnectionModeDropDownLabel.Text = 'Connection Mode';
            app.ConnectionModeDropDown = uidropdown(app.ConfigurationsPanel_2);
            app.ConnectionModeDropDown.Items = {'SERIES', 'FEEDBACK'};
            app.ConnectionModeDropDown.Value = 'SERIES';
            app.ControllerPanel = uipanel(app.ClosedLoopTab);
            app.ControllerPanel.Title = 'Controller';
            app.NumeratorEditField_4Label = uilabel(app.ControllerPanel);
            app.NumeratorEditField_4Label.Text = 'Numerator';
            app.NumeratorEditField_4 = uieditfield(app.ControllerPanel, 'text');
            app.DenominatorEditField_4Label = uilabel(app.ControllerPanel);
            app.DenominatorEditField_4Label.Text = 'Denominator';
            app.DenominatorEditField_4 = uieditfield(app.ControllerPanel, 'text');
            app.SampleTimeEditField_2Label = uilabel(app.ControllerPanel);
            app.SampleTimeEditField_2Label.Text = 'Sample Time';
            app.SampleTimeEditField_2 = uieditfield(app.ControllerPanel, 'text');
            app.VerifyButton_2 = uibutton(app.ClosedLoopTab, 'push');
            app.VerifyButton_2.ButtonPushedFcn = createCallbackFcn(app, @VerifyClosedLoopButtonPushed, true);
            app.VerifyButton_2.Text = 'Verify';
            app.ResetButton_2 = uibutton(app.ClosedLoopTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonClosedLoopPushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.StateSpaceTab = uitab(app.TabGroup);
            app.StateSpaceTab.Title = 'State-Space';
            app.StateSpaceTab.Units = 'pixels';
            app.StateSpaceMatrixABCDPanel = uipanel(app.StateSpaceTab);
            app.StateSpaceMatrixABCDPanel.Title = 'State-Space Matrix (A, B, C, D)';
            app.AEditFieldLabel = uilabel(app.StateSpaceMatrixABCDPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.StateSpaceMatrixABCDPanel, 'text');
            app.BEditFieldLabel = uilabel(app.StateSpaceMatrixABCDPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.StateSpaceMatrixABCDPanel, 'text');
            app.CEditFieldLabel = uilabel(app.StateSpaceMatrixABCDPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.StateSpaceMatrixABCDPanel, 'text');
            app.DEditFieldLabel = uilabel(app.StateSpaceMatrixABCDPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.StateSpaceMatrixABCDPanel, 'text');
            app.ImplementationPanel_3 = uipanel(app.StateSpaceTab);
            app.ImplementationPanel_3.Title = 'Implementation';
            app.IntegerBitsSpinner_3Label = uilabel(app.ImplementationPanel_3);
            app.IntegerBitsSpinner_3Label.Text = 'Integer Bits';
            app.IntegerBitsSpinner_3 = uispinner(app.ImplementationPanel_3);
            app.FractionalBitsSpinner_3Label = uilabel(app.ImplementationPanel_3);
            app.FractionalBitsSpinner_3Label.Text = 'Fractional Bits';
            app.FractionalBitsSpinner_3 = uispinner(app.ImplementationPanel_3);
            app.InputMatrixEditFieldLabel = uilabel(app.ImplementationPanel_3);
            app.InputMatrixEditFieldLabel.Text = 'Input Matrix';
            app.InputMatrixEditField = uieditfield(app.ImplementationPanel_3, 'text');
            app.maxrangeSpinner_3Label = uilabel(app.ImplementationPanel_3);
            app.maxrangeSpinner_3Label.Text = 'max range';
            app.maxrangeSpinner_3 = uispinner(app.ImplementationPanel_3);
            app.maxrangeSpinner_3.Value = 1;
            app.minrangeSpinner_3Label = uilabel(app.ImplementationPanel_3);
            app.minrangeSpinner_3Label.Text = 'min range';
            app.minrangeSpinner_3 = uispinner(app.ImplementationPanel_3);
            app.minrangeSpinner_3.Value = -1;
            app.KMatrixEditFieldLabel = uilabel(app.ImplementationPanel_3);
            app.KMatrixEditFieldLabel.Text = 'K Matrix';
            app.KMatrixEditField = uieditfield(app.ImplementationPanel_3, 'text');
            app.KboundSliderLabel = uilabel(app.ImplementationPanel_3);
            app.KboundSliderLabel.Text = 'K bound';
            app.KboundSlider = uislider(app.ImplementationPanel_3);
            app.ErrorLimitEditField_3Label = uilabel(app.ImplementationPanel_3);
            app.ErrorLimitEditField_3Label.Text = 'Error Limit';
            app.ErrorLimitEditField_3 = uieditfield(app.ImplementationPanel_3, 'text');
            app.ConfigurationsPanel_3 = uipanel(app.StateSpaceTab);
            app.ConfigurationsPanel_3.Title = 'Configurations';
            app.BMCDropDown_3Label = uilabel(app.ConfigurationsPanel_3);
            app.BMCDropDown_3Label.Text = 'BMC';
            app.BMCDropDown_3 = uidropdown(app.ConfigurationsPanel_3);
            app.BMCDropDown_3.Items = {'ESBMC', 'CBMC'};
            app.BMCDropDown_3.Value = 'CBMC';
            app.SolverDropDown_3Label = uilabel(app.ConfigurationsPanel_3);
            app.SolverDropDown_3Label.Text = 'Solver';
            app.SolverDropDown_3 = uidropdown(app.ConfigurationsPanel_3);
            app.SolverDropDown_3.Items = {'Boolector', 'Z3'};
            app.SolverDropDown_3.Value = 'Boolector';
            app.VerifyButton_3 = uibutton(app.StateSpaceTab, 'push');
            app.VerifyButton_3.ButtonPushedFcn = createCallbackFcn(app, @VerifyStateSpaceButtonPushed, true);
            app.VerifyButton_3.Text = 'Verify';
            app.ResetButton_3 = uibutton(app.StateSpaceTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonStateSpacePushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.PropertiesPanel_3 = uipanel(app.StateSpaceTab);
            app.PropertiesPanel_3.Title = 'Properties';
            app.StabilityCheckBox_2 = uicheckbox(app.PropertiesPanel_3);
            app.StabilityCheckBox_2.Text = 'Stability';
            app.QuantizationErrorCheckBox_2 = uicheckbox(app.PropertiesPanel_3);
            app.QuantizationErrorCheckBox_2.Text = 'Quantization Error';
            app.ObservabilityCheckBox = uicheckbox(app.PropertiesPanel_3);
            app.ObservabilityCheckBox.Text = 'Observability';
            app.ControllabilityCheckBox = uicheckbox(app.PropertiesPanel_3);
            app.ControllabilityCheckBox.Text = 'Controllability';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DSVerifier()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSVerifierAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSVerifierAppUIFigure)
        end
    end
end